classdef BeidouAPP2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        V10UIFigure                   matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        LoadSDRParaConfig             matlab.ui.container.Menu
        SaveSDRParaConfig             matlab.ui.container.Menu
        SaveTable                     matlab.ui.container.Menu
        Menu_3                        matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        Panel_10                      matlab.ui.container.Panel
        ClearLogButton                matlab.ui.control.Button
        SaveLogButton                 matlab.ui.control.Button
        Panel_3                       matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        Panel                         matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        Panel_9                       matlab.ui.container.Panel
        UTMPanel                      matlab.ui.container.Panel
        UEditField                    matlab.ui.control.EditField
        UEditFieldLabel               matlab.ui.control.Label
        NEditField                    matlab.ui.control.EditField
        NEditFieldLabel               matlab.ui.control.Label
        EEditField                    matlab.ui.control.EditField
        EEditFieldLabel               matlab.ui.control.Label
        ElevationmaskEditField        matlab.ui.control.EditField
        ElevationmaskLabel            matlab.ui.control.Label
        NavsolperiodmsEditField       matlab.ui.control.EditField
        NavsolperiodmsEditFieldLabel  matlab.ui.control.Label
        DLLPanel                      matlab.ui.container.Panel
        CorrspacingEditField          matlab.ui.control.EditField
        CorrspacingEditFieldLabel     matlab.ui.control.Label
        DllDampingratioEditField      matlab.ui.control.EditField
        DampingratioEditField_2Label  matlab.ui.control.Label
        DllBandwidthEditField         matlab.ui.control.EditField
        BandwidthHzLabel_2            matlab.ui.control.Label
        PLLPanel                      matlab.ui.container.Panel
        PllDampingratioEditField      matlab.ui.control.EditField
        DampingratioEditFieldLabel    matlab.ui.control.Label
        PllBandwidthEditField         matlab.ui.control.EditField
        BandwidthHzLabel              matlab.ui.control.Label
        Panel_8                       matlab.ui.container.Panel
        NofchannelsEditField          matlab.ui.control.EditField
        NofchannelsEditFieldLabel     matlab.ui.control.Label
        Panel_7                       matlab.ui.container.Panel
        DatatypeEditField             matlab.ui.control.EditField
        DatatypeEditFieldLabel        matlab.ui.control.Label
        NofmstoprocessEditField       matlab.ui.control.EditField
        NofmstoprocessEditFieldLabel  matlab.ui.control.Label
        NofbytestoskipEditField       matlab.ui.control.EditField
        NofbytestoskipEditFieldLabel  matlab.ui.control.Label
        Panel_6                       matlab.ui.container.Panel
        DetthresholdEditField         matlab.ui.control.EditField
        DetthresholdEditFieldLabel    matlab.ui.control.Label
        AcqbandkHzEditField           matlab.ui.control.EditField
        AcqbandkHzLabel               matlab.ui.control.Label
        Panel_5                       matlab.ui.container.Panel
        HelpParaButton                matlab.ui.control.Button
        PrintParaButton               matlab.ui.control.Button
        DefaultParaButton             matlab.ui.control.Button
        StartSimButton                matlab.ui.control.Button
        Panel_4                       matlab.ui.container.Panel
        SelectdatafileButton          matlab.ui.control.Button
        DataFileEditField             matlab.ui.control.EditField
        DataFileEditFieldLabel        matlab.ui.control.Label
        codeLengthEditField           matlab.ui.control.EditField
        codeLengthEditFieldLabel      matlab.ui.control.Label
        codeFreqBasisEditField        matlab.ui.control.EditField
        codeFreqBasisEditFieldLabel   matlab.ui.control.Label
        samplingFreqEditField         matlab.ui.control.EditField
        samplingFreqEditFieldLabel    matlab.ui.control.Label
        IFEditField                   matlab.ui.control.EditField
        IFLabel                       matlab.ui.control.Label
        Tab_4                         matlab.ui.container.Tab
        SelectBySubrameDropDown       matlab.ui.control.DropDown
        Label_3                       matlab.ui.control.Label
        SelectByDataDropDown          matlab.ui.control.DropDown
        Label_2                       matlab.ui.control.Label
        HelpNavSolveButton            matlab.ui.control.Button
        NavSolveButton                matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        Tab_2                         matlab.ui.container.Tab
        Panel_11                      matlab.ui.container.Panel
        BCHSimResultTable             matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
        Panel_2                       matlab.ui.container.Panel
        Panel_12                      matlab.ui.container.Panel
        SNRStepEditField              matlab.ui.control.EditField
        Label_5                       matlab.ui.control.Label
        SNREndEditField               matlab.ui.control.EditField
        Label_4                       matlab.ui.control.Label
        SNRStartEditField             matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        SetChannel                    matlab.ui.container.ButtonGroup
        RayleighButton                matlab.ui.control.RadioButton
        AWGNButton                    matlab.ui.control.RadioButton
        StartBCHSimButton             matlab.ui.control.Button
        DecodeScheme                  matlab.ui.container.ButtonGroup
        Chase2Button                  matlab.ui.control.RadioButton
        RmFCSPAButton                 matlab.ui.control.RadioButton
        ICDButton                     matlab.ui.control.RadioButton
    end

    properties (Access = private)
        eph % Description
        settings
        currentSettings
        NavMsgData
        BCHSimSettings
        navSolveFlag
    end

    methods (Access = public)

        function main(app)
            format ('compact');
            format ('long', 'g');

            addpath include             % The software receiver functions
            addpath geoFunctions        % Position calculation related functions
            app.currentSettings = app.settings;
            try
                app.TextArea.Value = [app.TextArea.Value;(sprintf('Probing data (%s)...\n', app.currentSettings.fileName))];
                %sprintf('Probing data (%s)...\n', app.settings.fileName)
                probeData(app.currentSettings);
            catch
                errStruct = lasterror;
                disp(errStruct.message);
                app.TextArea.Value = [app.TextArea.Value;"(please change settings in initSettings.m to reconfigure)"];
                return;
            end

            app.TextArea.Value = [app.TextArea.Value;"Raw IF data plotted"];

            f1(app);%postProcessing;
        end

        function f1(app)
            app.currentSettings = app.settings;
            %postProcessing;

            app.TextArea.Value = [app.TextArea.Value;"Starting processing..."];

            [fid, message] = fopen(app.currentSettings.fileName, 'rb');

            if (app.currentSettings.fileType==1)
                dataAdaptCoeff=1;
            else
                dataAdaptCoeff=2;
            end

            if (fid > 0)
                fseek(fid, dataAdaptCoeff*app.currentSettings.skipNumberOfSamples, 'bof');
                if ((app.currentSettings.skipAcquisition == 0))% || ~exist('acqResults', 'var'))
                    samplesPerCode = round(app.currentSettings.samplingFreq / ...
                        (app.currentSettings.codeFreqBasis / app.currentSettings.codeLength));
                    app.TextArea.Value = [app.TextArea.Value;"Acquiring satellites..."];

                    data = fread(fid, ...
                        dataAdaptCoeff*(app.currentSettings.acquisition.cohCodePeriods*app.currentSettings.acquisition.nonCohSums+1)*samplesPerCode*6,app.currentSettings.dataType)';

                    if (dataAdaptCoeff==2)
                        data1=data(1:2:end);
                        data2=data(2:2:end);
                        data=data1 + 1i .* data2;
                    end

                    acqResults = acquisition(data, app.currentSettings);

                    % Plot the acquisition results
                    plotAcquisition(acqResults);
                elseif(app.currentSettings.skipAcquisition == 1)
                    disp('skipAcquistion==1');
                    load acqresults
                    plotAcquisition(acqResults);
                end


                if (any(acqResults.peakMetric>app.currentSettings.acqThreshold))
                    channel = preRun(acqResults, app.currentSettings);
                    app.TextArea.Value = [app.TextArea.Value;sprintf('\n*=======*=====*===============*===========*=============*========*\n')];
                    app.TextArea.Value = [app.TextArea.Value;sprintf(  '| Channel | PRN |   Frequency   |  Doppler  | Code Offset | Status |\n')];
                    app.TextArea.Value = [app.TextArea.Value;sprintf(  '*=======*=====*===============*===========*=============*========*\n')];

                    for channelNr = 1 : app.currentSettings.numberOfChannels
                        if (channel(channelNr).status ~= '-')
                            app.TextArea.Value = [app.TextArea.Value;sprintf('|        %2d   |    %3d  |  %2.5e |   %5.0f   |    %6d   |     %1s  |\n', ...
                                channelNr, ...
                                channel(channelNr).PRN, ...
                                channel(channelNr).acquiredFreq, ...
                                channel(channelNr).acquiredFreq - app.currentSettings.IF, ...
                                channel(channelNr).codePhase, ...
                                channel(channelNr).status)];
                        else
                            app.TextArea.Value = [app.TextArea.Value;sprintf('|        %2d   |  ---- |  ------------- |  -------  |    ------   |   Off  |\n',channelNr)];
                        end
                    end

                    app.TextArea.Value = [app.TextArea.Value;sprintf('*=======*=====*===============*===========*=============*========*\n\n')];
                    scroll(app.TextArea, 'bottom');
                    %showChannelStatus(channel, app.currentSettings);
                else
                    % No satellites to track, exit
                    app.TextArea.Value = [app.TextArea.Value;sprintf('No GNSS signals detected, signal processing finished.\n')];
                    trackResults = [];
                    return;
                end

                %% Track the signal =======================================================
                if ~app.currentSettings.VLLen

                    startTime = now;
                    app.TextArea.Value = [app.TextArea.Value; sprintf(['Tracking started at ', datestr(startTime),'\n'])];

                    % Process all channels for given data block

                    [trackResults, channel] = tracking(fid, channel, app.currentSettings);

                    % Close the data file
                    fclose(fid);

                    app.TextArea.Value = [app.TextArea.Value; sprintf(['Tracking is over (elapsed time ', datestr(now - startTime, 13), ')'])];

                    settings = app.currentSettings;
                    % Auto save the acquisition & tracking results to a file to allow
                    % running the positioning solution afterwards.
                    app.TextArea.Value = [app.TextArea.Value; sprintf('Saving Acq & Tracking results to file "trackingResults.mat"')];
                    scroll(app.TextArea, 'bottom');
                    save('trackingResults', 'trackResults', 'settings', 'acqResults', 'channel');
                    %%%%%%%%%% when delete, uncomment above
                    load trackingResults

                else
                    disp('skip scalar tracking, load exisiting results')

                    load 'trackingResults'
                    app.currentSettings.VLLen = 1;
                end

                %% Calculate navigation solutions =========================================

                if ~app.currentSettings.VLLen
                    app.TextArea.Value = [app.TextArea.Value; sprintf('Calculating navigation solutions...')];
                    scroll(app.TextArea, 'bottom');
                    [navSolutions, eph, svTimeTable,activeChnList] = postNavigation(trackResults, app.currentSettings);
                    save('navSolutions','navSolutions','eph','svTimeTable','activeChnList')
                    load 'navSolutions'
                else
                    disp('skip postNavigation, load existing navSolution')
                    %input the path and file name of the existing navSolution saved
                    %from scalar tracking and calculation
                    load 'navSolutions'
                    disp('start vector tracking')
                    return
                end

                app.TextArea.Value = [app.TextArea.Value; sprintf('Processing is complete for this data block\n')];
                %% Plot all results ===================================================
                app.TextArea.Value = [app.TextArea.Value; sprintf('Ploting results...\n')];
                scroll(app.TextArea, 'bottom');
                if app.currentSettings.plotTracking
                    plotTracking(1:app.currentSettings.numberOfChannels, trackResults, app.currentSettings);
                end

                plotNavigation(navSolutions, app.currentSettings);

                app.TextArea.Value = [app.TextArea.Value; sprintf('Post processing of the signal is over.\n')];
                scroll(app.TextArea, 'bottom');

                ephPRN = sort([channel.PRN].');
                ephPRN(ephPRN == 0) = [];
                %打印eph
                ephPrint = eph(ephPRN);
                app.NavMsgData = table([ephPrint.weekNumber].',[ephPrint.accuracy].',[ephPrint.health].',[ephPrint.T_GD].',[ephPrint.IODC].',[ephPrint.t_oc].',[ephPrint.a_f2].',[ephPrint.a_f1].',[ephPrint.a_f0].',...
                    [ephPrint.IODE_sf2].',[ephPrint.C_rs].',[ephPrint.deltan].',[ephPrint.M_0].',[ephPrint.C_uc].',[ephPrint.e].',[ephPrint.C_us].',[ephPrint.sqrtA].',[ephPrint.t_oe].',[ephPrint.C_ic].',[ephPrint.omega_0].',[ephPrint.C_ic].',...
                    [ephPrint.i_0].',[ephPrint.C_rc].',[ephPrint.omega].',[ephPrint.omegaDot].',[ephPrint.IODE_sf3].');
                app.UITable.Data = app.NavMsgData;

            else
                % Error while opening the data file.
                error('Unable to read file %s: %s.', app.currentSettings.fileName, message);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TextArea.Value = "欢迎使用北斗多功能通信仿真系统 V1.0!";
            app.settings = initSettings; %初始化接收机参数
            app.IFEditField.Value = "38400";
            app.samplingFreqEditField.Value = "8.1838e6";
            app.codeFreqBasisEditField.Value = "1.023e6";
            app.codeLengthEditField.Value = "1023";
            app.AcqbandkHzEditField.Value = "14";
            app.DetthresholdEditField.Value = "2.5";
            app.NofbytestoskipEditField.Value = "0";
            app.NofmstoprocessEditField.Value = "36000";
            app.DatatypeEditField.Value = "2";
            app.NofchannelsEditField.Value = "8";
            app.PllBandwidthEditField.Value = "6.5";
            app.PllDampingratioEditField.Value = "0.7";
            app.DllBandwidthEditField.Value = "1";
            app.DllDampingratioEditField.Value = "0.7";
            app.CorrspacingEditField.Value = "0.5";
            app.NavsolperiodmsEditField.Value = "100";
            app.ElevationmaskEditField.Value = "10";
            app.SNRStartEditField.Value = "1";
            app.SNRStepEditField.Value = "1";
            app.SNREndEditField.Value = "10";
            app.BCHSimSettings.SNRStart = 1;
            app.BCHSimSettings.SNRStep = 1;
            app.BCHSimSettings.SNREnd = 10;
            app.navSolveFlag = 0;

        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            myicon = imread('SWJTU_shield.svg');
            msgbox({'北斗多功能通信仿真系统';'西南交通大学';'作者：赵文林，李里'},'关于...','custom',myicon);
        end

        % Button pushed function: StartSimButton
        function StartSimButtonPushed(app, event)
            app.TextArea.Value = sprintf('接收机正在运行，请待数据处理结束后使用其他功能！\n');
            main(app);
        end

        % Callback function
        function Button2Pushed(app, event)
            app.TextArea.Value = [app.TextArea.Value;num2str(app.settings.IF)];
        end

        % Value changed function: IFEditField
        function IFEditFieldValueChanged(app, event)
            app.settings.IF = str2doubleq(app.IFEditField.Value);
        end

        % Value changed function: samplingFreqEditField
        function samplingFreqEditFieldValueChanged(app, event)
            app.settings.samplingFreq = str2doubleq(app.samplingFreqEditField.Value);
        end

        % Value changed function: codeFreqBasisEditField
        function codeFreqBasisEditFieldValueChanged(app, event)
            app.settings.codeFreqBasis = str2doubleq(app.codeFreqBasisEditField.Value);
        end

        % Value changed function: codeLengthEditField
        function codeLengthEditFieldValueChanged(app, event)
            app.settings.codeLength = str2doubleq(app.codeLengthEditField.Value);
        end

        % Button pushed function: SelectdatafileButton
        function SelectdatafileButtonPushed(app, event)
            [file,path] = uigetfile({'*.bin';'*.dat';'*.*'},'Select the data file');%可后接默认文件名称
            app.settings.fileName = fullfile(path,file);
            app.DataFileEditField.Value = fullfile(path,file);
        end

        % Value changed function: AcqbandkHzEditField
        function AcqbandkHzEditFieldValueChanged(app, event)
            app.settings.acqSearchBand = str2doubleq(app.AcqbandkHzEditField.Value);
        end

        % Value changed function: DetthresholdEditField
        function DetthresholdEditFieldValueChanged(app, event)
            app.settings.acqThreshold = str2doubleq(app.DetthresholdEditField.Value);
        end

        % Value changed function: NofbytestoskipEditField
        function NofbytestoskipEditFieldValueChanged(app, event)
            app.settings.skipNumberOfBytes = str2doubleq(app.NofbytestoskipEditField.Value);
        end

        % Value changed function: NofmstoprocessEditField
        function NofmstoprocessEditFieldValueChanged(app, event)
            app.settings.msToProcess = str2doubleq(app.NofmstoprocessEditField.Value);
        end

        % Value changed function: DatatypeEditField
        function DatatypeEditFieldValueChanged(app, event)
            app.settings.fileType = str2doubleq(app.DatatypeEditField.Value);
        end

        % Value changed function: NofchannelsEditField
        function NofchannelsEditFieldValueChanged(app, event)
            app.settings.numberOfChannels = str2doubleq(app.NofchannelsEditField.Value);
        end

        % Value changed function: PllBandwidthEditField
        function PllBandwidthEditFieldValueChanged(app, event)
            app.settings.pllNoiseBandwidth = str2doubleq(app.PllBandwidthEditField.Value);
        end

        % Value changed function: PllDampingratioEditField
        function PllDampingratioEditFieldValueChanged(app, event)
            app.settings.pllDampingRatio = str2doubleq(app.PllDampingratioEditField.Value);
        end

        % Value changed function: DllBandwidthEditField
        function DllBandwidthEditFieldValueChanged(app, event)
            app.settings.dllNoiseBandwidth = str2doubleq(app.DllBandwidthEditField.Value);
        end

        % Value changed function: DllDampingratioEditField
        function DllDampingratioEditFieldValueChanged(app, event)
            app.settings.dllDampingRatio = str2doubleq(app.DllDampingratioEditField.Value);
        end

        % Value changed function: CorrspacingEditField
        function CorrspacingEditFieldValueChanged(app, event)
            app.settings.dllCorrelatorSpacing = str2doubleq(app.CorrspacingEditField.Value);
        end

        % Value changed function: NavsolperiodmsEditField
        function NavsolperiodmsEditFieldValueChanged(app, event)
            app.settings.navSolPeriod = str2doubleq(app.NavsolperiodmsEditField.Value);
        end

        % Value changed function: ElevationmaskEditField
        function ElevationmaskEditFieldValueChanged(app, event)
            app.settings.elevationMask = str2doubleq(app.ElevationmaskEditField.Value);
        end

        % Value changed function: EEditField
        function EEditFieldValueChanged(app, event)
            app.settings.truePosition.E = str2doubleq(app.EEditField.Value);
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            app.settings.truePosition.N = str2doubleq(app.NEditField.Value);
        end

        % Value changed function: UEditField
        function UEditFieldValueChanged(app, event)
            app.settings.truePosition.U = str2doubleq(app.UEditField.Value);
        end

        % Button pushed function: DefaultParaButton
        function DefaultParaButtonPushed(app, event)
            app.settings = initSettings;
            app.TextArea.Value = "恢复默认参数！";
            app.IFEditField.Value = "38400";
            app.samplingFreqEditField.Value = "8.1838e6";
            app.codeFreqBasisEditField.Value = "1.023e6";
            app.codeLengthEditField.Value = "1023";
            app.AcqbandkHzEditField.Value = "14";
            app.DetthresholdEditField.Value = "2.5";
            app.NofbytestoskipEditField.Value = "0";
            app.NofmstoprocessEditField.Value = "36000";
            app.DatatypeEditField.Value = "2";
            app.NofchannelsEditField.Value = "8";
            app.PllBandwidthEditField.Value = "6.5";
            app.PllDampingratioEditField.Value = "0.7";
            app.DllBandwidthEditField.Value = "1";
            app.DllDampingratioEditField.Value = "0.7";
            app.CorrspacingEditField.Value = "0.5";
            app.NavsolperiodmsEditField.Value = "100";
            app.ElevationmaskEditField.Value = "10";
        end

        % Button pushed function: PrintParaButton
        function PrintParaButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value;sprintf('\n--------------------------------------------------------------------\nsettings =\nmsToProcess: %d\nnumberOfChannels: %d\nskipNumberOfSamples: %d\nskipNumberOfBytes: %d\nfileName: %s\ndataType: %s\nfileType: %d\nIF: %d\nsamplingFreq: %d\ncodeFreqBasis: %d\ncodeLength: %d\nskipAcquisition: %d\nacqSatelliteList: [%s]\nacqSearchBand: %d\nacqThreshold: %f\ndllDampingRatio: %f\ndllNoiseBandwidth: %f\ndllCorrelatorSpacing: %f\npllDampingRatio: %f\npllNoiseBandwidth: %f\nfllDamping: %f\nfllNoiseBandwidth: %f\nnavSolPeriod: %d\nelevationMask: %d\nuseTropCorr: %d\nc: %d\nstartOffset: %f\nVLLen: %d',app.settings.msToProcess,app.settings.numberOfChannels,app.settings.skipNumberOfSamples,app.settings.skipNumberOfBytes,app.settings.fileName,app.settings.dataType,app.settings.fileType,app.settings.IF,app.settings.samplingFreq,app.settings.codeFreqBasis,app.settings.codeLength,app.settings.skipAcquisition,num2str(app.settings.acqSatelliteList),app.settings.acqSearchBand,app.settings.acqThreshold,app.settings.dllDampingRatio,app.settings.dllNoiseBandwidth,app.settings.dllCorrelatorSpacing,app.settings.pllDampingRatio,app.settings.pllNoiseBandwidth,app.settings.fllDampingRatio,app.settings.fllNoiseBandwidth,app.settings.navSolPeriod,app.settings.elevationMask,app.settings.useTropCorr,app.settings.c,app.settings.startOffset,app.settings.VLLen)];
            scroll(app.TextArea, 'bottom');
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            app.TextArea.Value = "";
        end

        % Button pushed function: HelpParaButton
        function HelpParaButtonPushed(app, event)
            helpMessage = fileread('APPDesign\helpPara.txt');
            msgbox(helpMessage,"参数设置帮助","non-modal");
        end

        % Button pushed function: SaveLogButton
        function SaveLogButtonPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value;sprintf("\n系统消息已成功保存至Output\\SystemLog.txt！")];
            scroll(app.TextArea, 'bottom');
            writecell(app.TextArea.Value,"Output\SystemLog.txt","FileType","text");
        end

        % Menu selected function: SaveSDRParaConfig
        function SaveSDRParaConfigMenuSelected(app, event)
            settings = app.settings;
            file = uiputfile('*.mat','Save Config','SDRConfig.mat');
            save(file,'settings')
            app.TextArea.Value = [app.TextArea.Value;sprintf("\n成功保存当前参数！")];
            scroll(app.TextArea, 'bottom');
        end

        % Menu selected function: LoadSDRParaConfig
        function LoadSDRParaConfigMenuSelected(app, event)
            file = uigetfile('*.mat','Load Config','SDRConfig.mat');
            load(file,"settings");
            app.settings = settings;
            app.TextArea.Value = [app.TextArea.Value;sprintf("\n成功加载配置！")];
            app.IFEditField.Value = sprintf("%.2f",app.settings.IF);
            app.samplingFreqEditField.Value = sprintf("%.2f",app.settings.samplingFreq);
            app.codeFreqBasisEditField.Value = sprintf("%.2f",app.settings.codeFreqBasis);
            app.codeLengthEditField.Value = sprintf("%d",app.settings.codeLength);
            app.DataFileEditField.Value = sprintf("%s",app.settings.fileName);
            app.AcqbandkHzEditField.Value = sprintf("%.2f",app.settings.acqSearchBand);
            app.DetthresholdEditField.Value = sprintf("%.2f",app.settings.acqThreshold);
            app.NofbytestoskipEditField.Value = sprintf("%d",app.settings.skipNumberOfBytes);
            app.NofmstoprocessEditField.Value = sprintf("%d",app.settings.msToProcess);
            app.DatatypeEditField.Value = sprintf("%d",app.settings.fileType);
            app.NofchannelsEditField.Value = sprintf("%d",app.settings.numberOfChannels);
            app.PllBandwidthEditField.Value = sprintf("%.2f",app.settings.pllNoiseBandwidth);
            app.PllDampingratioEditField.Value = sprintf("%.2f",app.settings.pllDampingRatio);
            app.DllBandwidthEditField.Value = sprintf("%.2f",app.settings.dllNoiseBandwidth);
            app.DllDampingratioEditField.Value = sprintf("%.2f",app.settings.dllDampingRatio);
            app.CorrspacingEditField.Value = sprintf("%.2f",app.settings.dllCorrelatorSpacing);
            app.NavsolperiodmsEditField.Value = sprintf("%d",app.settings.navSolPeriod);
            app.ElevationmaskEditField.Value = sprintf("%d",app.settings.elevationMask);
            scroll(app.TextArea, 'bottom');
        end

        % Button pushed function: NavSolveButton
        function NavSolveButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt';'*.*'},'Select the nav file');%可后接默认文件名称
            filepath = fullfile(path,file);
            app.eph = D1NavSolveAPP(filepath);
            app.navSolveFlag = 1;
            app.TextArea.Value = [app.TextArea.Value;sprintf("\n电文解析完成！")];
            scroll(app.TextArea, 'bottom');
        end

        % Value changed function: SelectBySubrameDropDown
        function SelectBySubrameDropDownValueChanged(app, event)
            value = app.SelectBySubrameDropDown.Value;
            if app.navSolveFlag == 1

                if value == "子帧一"
                    app.UITable.ColumnName = {'SOW';'SatH1';'AODC';'URAI';'WN';'t_oc';'T_GD1';'T_GD2';'alpha0';'alpha1';'alpha2';'alpha3';'beta0';'beta1';'beta2';'beta3';'a2';'a0';'a1';'AODE'};
                    subframe = [];
                    for i = 1:length(app.eph)
                        subframe = [subframe app.eph(i).Sub1];
                    end
                    app.UITable.Data = table([subframe.SOW]',[subframe.SatH1]',[subframe.AODC]',[subframe.URAI]',[subframe.WN]',[subframe.t_oc]',[subframe.T_GD1]',[subframe.T_GD2]',[subframe.alpha0]',[subframe.alpha1]',[subframe.alpha2]',[subframe.alpha3]',[subframe.beta0]',[subframe.beta1]',[subframe.beta2]',[subframe.beta3]',[subframe.a2]',[subframe.a0]',[subframe.a1]',[subframe.AODE]');
                elseif value == "子帧二"
                    app.UITable.ColumnName = {'SOW';'deltan';'C_uc';'M_0';'e';'C_us';'C_rc';'C_rs';'sqrtA'};
                    subframe = [];
                    for i = 1:length(app.eph)
                        subframe = [subframe app.eph(i).Sub2];
                    end
                    app.UITable.Data = table([subframe.SOW]',[subframe.deltan]',[subframe.C_uc]',[subframe.M_0]',[subframe.e]',[subframe.C_us]',[subframe.C_rc]',[subframe.C_rs]',[subframe.sqrtA]');
                elseif value == "子帧三"
                    app.UITable.ColumnName = {'SOW';'t_oe';'i_0';'C_ic';'Omega_dot';'C_is';'IDOT';'Omega_0';'omega'};
                    subframe = [];
                    for i = 1:length(app.eph)
                        subframe = [subframe app.eph(i).Sub3];
                    end
                    app.UITable.Data = table([subframe.SOW]',[subframe.t_oe]',[subframe.i_0]',[subframe.C_ic]',[subframe.Omega_dot]',[subframe.C_is]',[subframe.IDOT]',[subframe.Omega_0]',[subframe.omega]');
                elseif value == "子帧四"
                    app.UITable.ColumnName = {'SOW';'Pnum';'sqrtA';'a1';'a0';'Omega_0';'e';'delta_i';'t_oa';'Omega_dot';'omega';'M_0'};
                    subframe = [];
                    for i = 1:length(app.eph)
                        subframe = [subframe app.eph(i).Sub4];
                    end
                    app.UITable.Data = table([subframe.SOW]',[subframe.Pnum]',[subframe.sqrtA]',[subframe.a1]',[subframe.a0]',[subframe.Omega_0]',[subframe.e]',[subframe.delta_i]',[subframe.t_oa]',[subframe.Omega_dot]',[subframe.omega]',[subframe.M_0]');
                else
                    return
                end
            else
                return
            end
        end

        % Value changed function: SelectByDataDropDown
        function SelectByDataDropDownValueChanged(app, event)
            value = app.SelectByDataDropDown.Value;
            if app.navSolveFlag == 1
                if value == "星历16参"
                    app.UITable.ColumnName = {'SOW';'t_oe';'sqrtA';'e';'i_0';'Omega_0';'omega';'M_0';'deltan';'IDOT';'Omega_dot';'C_uc';'C_us';'C_rc';'C_rs';'C_ic';'C_is'};
                    data = [];
                    for i = 1:length(app.eph)
                        %                     if isempty(app.eph(i).Sub2) && ~isempty(app.eph(i).Sub3)
                        %                         data = [data;app.eph(i).Sub3.SOW app.eph(i).Sub3.t_oe nan nan app.eph(i).Sub3.i_0 app.eph(i).Sub3.Omega_0 app.eph(i).Sub3.omega nan nan ...
                        %                             app.eph(i).Sub3.IDOT app.eph(i).Sub3.Omega_dot nan nan nan nan app.eph(i).Sub3.C_ic app.eph(i).Sub3.C_is];
                        %                     elseif isempty(app.eph(i).Sub3) && ~isempty(app.eph(i).Sub2)
                        %                         data = [data;app.eph(i).Sub2.SOW nan app.eph(i).Sub2.sqrtA app.eph(i).Sub2.e nan nan nan app.eph(i).Sub2.M_0 app.eph(i).Sub2.deltan ...
                        %                             nan nan app.eph(i).Sub2.C_uc app.eph(i).Sub2.C_us app.eph(i).Sub2.C_rc app.eph(i).Sub2.C_rs nan nan];
                        %                     elseif isempty(app.eph(i).Sub3) && isempty(app.eph(i).Sub2)
                        %                     else
                        %                         data = [data;app.eph(i).Sub2.SOW app.eph(i).Sub3.t_oe app.eph(i).Sub2.sqrtA app.eph(i).Sub2.e app.eph(i).Sub3.i_0 app.eph(i).Sub3.Omega_0 app.eph(i).Sub3.omega app.eph(i).Sub2.M_0 app.eph(i).Sub2.deltan ...
                        %                             app.eph(i).Sub3.IDOT app.eph(i).Sub3.Omega_dot app.eph(i).Sub2.C_uc app.eph(i).Sub2.C_us app.eph(i).Sub2.C_rc app.eph(i).Sub2.C_rs app.eph(i).Sub3.C_ic app.eph(i).Sub3.C_is];
                        %                     end
                        data = [data;app.eph(i).Sub2.SOW app.eph(i).Sub3.t_oe app.eph(i).Sub2.sqrtA app.eph(i).Sub2.e app.eph(i).Sub3.i_0 app.eph(i).Sub3.Omega_0 app.eph(i).Sub3.omega app.eph(i).Sub2.M_0 app.eph(i).Sub2.deltan app.eph(i).Sub3.IDOT...
                            app.eph(i).Sub3.Omega_dot app.eph(i).Sub2.C_uc app.eph(i).Sub2.C_us app.eph(i).Sub2.C_rc app.eph(i).Sub2.C_rs app.eph(i).Sub3.C_ic app.eph(i).Sub3.C_is];

                        app.UITable.Data = table(data(:,1),data(:,2),data(:,3),data(:,4),data(:,5),data(:,6),data(:,7),data(:,8),data(:,9),data(:,10),data(:,11),data(:,12),data(:,13),data(:,14),data(:,15),data(:,16),data(:,17));
                    end
                elseif value == "钟差数据"
                    app.UITable.ColumnName = {'SOW';'t_oc';'a0';'a1';'a2'};
                    subframe = [];
                    for i = 1:length(app.eph)
                        subframe = [subframe app.eph(i).Sub1];
                    end
                    app.UITable.Data = table([subframe.SOW]',[subframe.t_oc]',[subframe.a0]',[subframe.a1]',[subframe.a2]');
                elseif value == "历书数据"
                    app.UITable.ColumnName = {'SOW';'t_oa';'sqrtA';'e';'delta_i';'Omega_0';'omega';'M_0';'Omega_dot';'a0';'a1'};
                    subframe = [];
                    for i = 1:length(app.eph)
                        subframe = [subframe app.eph(i).Sub4];
                    end
                    app.UITable.Data = table([subframe.SOW]',[subframe.t_oa]',[subframe.sqrtA]',[subframe.e]',[subframe.delta_i]',[subframe.Omega_0]',[subframe.omega]',[subframe.M_0]',[subframe.Omega_dot]',[subframe.a0]',[subframe.a1]');
                elseif value == "电离层数据"
                    app.UITable.ColumnName = {'SOW';'alpha0';'alpha1';'alpha2';'alpha3';'beta0';'beta1';'beta2';'beta3'};
                    subframe = [];
                    for i = 1:length(app.eph)
                        subframe = [subframe app.eph(i).Sub1];
                    end
                    app.UITable.Data = table([subframe.SOW]',[subframe.alpha0]',[subframe.alpha1]',[subframe.alpha2]',[subframe.alpha3]',[subframe.beta0]',[subframe.beta1]',[subframe.beta2]',[subframe.beta3]');
                else
                    return
                end
            else
                return
            end
        end

        % Button pushed function: HelpNavSolveButton
        function HelpNavSolveButtonPushed(app, event)
            helpMessage = fileread('APPDesign\helpNavSolve.txt');
            msgbox(helpMessage,"导航电文解析帮助","non-modal");
        end

        % Menu selected function: SaveTable
        function SaveTableSelected(app, event)
            [filename, pathname, ~] = uiputfile({'*.xlsx;*.xls','All Excel Files (*.xlsx;*.xls)';}, 'Save Table','.\Output\navInfo');
            tableSave = app.UITable.Data;
            tableSave.Properties.VariableNames = app.UITable.ColumnName;
            writetable(tableSave,fullfile(pathname,filename));
            app.TextArea.Value = [app.TextArea.Value;sprintf("\n成功保存当前表格！")];
            scroll(app.TextArea, 'bottom');
        end

        % Selection changed function: DecodeScheme
        function DecodeSchemeSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'ICD标准译码'
                    app.BCHSimSettings.decodeScheme = 1;
                case 'RmFC+SPA译码'
                    app.BCHSimSettings.decodeScheme = 2;
                case 'Chase2译码'
                    app.BCHSimSettings.decodeScheme = 3;
            end
        end

        % Button pushed function: StartBCHSimButton
        function StartBCHSimButtonPushed(app, event)
            ebno_vec = app.BCHSimSettings.SNRStart:app.BCHSimSettings.SNRStep:app.BCHSimSettings.SNREnd;
            [ber,per] = BDBCH_APP(ebno_vec);
            app.BCHSimResultTable.Data = table(ebno_vec',ber,per);
            app.TextArea.Value = [app.TextArea.Value;sprintf("\n北斗BCH解调译码仿真结束！")];
            scroll(app.TextArea, 'bottom');

            % 绘图
            p = semilogy(app.UIAxes,ebno_vec, [per';ber']);
            p(1).Marker = 's';
            p(1).Color = [0.04 0.58 0.68];%blue[0 0.447 0.741]
            p(1).LineStyle = '-';
            p(2).Marker = '^';
            p(2).Color = [0.466 0.674 0.188];%red[0.9261 0.1211 0.1409]
            p(2).LineStyle = '-';
            l = legend(app.UIAxes,'per', 'ber');
            l.Location = 'SouthWest';
        end

        % Value changed function: SNRStartEditField
        function SNRStartEditFieldValueChanged(app, event)
            app.BCHSimSettings.SNRStart = str2double(app.SNRStartEditField.Value);
        end

        % Value changed function: SNREndEditField
        function SNREndEditFieldValueChanged(app, event)
            app.BCHSimSettings.SNREnd = str2double(app.SNREndEditField.Value);
        end

        % Value changed function: SNRStepEditField
        function SNRStepEditFieldValueChanged(app, event)
            app.BCHSimSettings.SNRStep = str2double(app.SNRStepEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.V10UIFigure = uifigure('Visible', 'off');
            app.V10UIFigure.Name = '北斗多功能通信仿真系统 V1.0';
            app.Menu = uimenu(app.V10UIFigure);
            app.Menu.Text = '文件';
            app.LoadSDRParaConfig = uimenu(app.Menu);
            app.LoadSDRParaConfig.MenuSelectedFcn = createCallbackFcn(app, @LoadSDRParaConfigMenuSelected, true);
            app.LoadSDRParaConfig.Text = '读取接收机参数';
            app.SaveSDRParaConfig = uimenu(app.Menu);
            app.SaveSDRParaConfig.MenuSelectedFcn = createCallbackFcn(app, @SaveSDRParaConfigMenuSelected, true);
            app.SaveSDRParaConfig.Text = '保存接收机参数';
            app.SaveTable = uimenu(app.Menu);
            app.SaveTable.MenuSelectedFcn = createCallbackFcn(app, @SaveTableSelected, true);
            app.SaveTable.Text = '保存导航电文解析表格';
            app.Menu_3 = uimenu(app.V10UIFigure);
            app.Menu_3.Text = '查看';
            app.AboutMenu = uimenu(app.V10UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = '关于';
            app.Panel = uipanel(app.V10UIFigure);
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '北斗接收机';
            app.Panel_4 = uipanel(app.Tab);
            app.Panel_4.Title = '信号参数设置';
            app.IFLabel = uilabel(app.Panel_4);
            app.IFLabel.Text = 'IF';
            app.IFEditField = uieditfield(app.Panel_4, 'text');
            app.IFEditField.ValueChangedFcn = createCallbackFcn(app, @IFEditFieldValueChanged, true);
            app.samplingFreqEditFieldLabel = uilabel(app.Panel_4);
            app.samplingFreqEditFieldLabel.Text = 'samplingFreq';
            app.samplingFreqEditField = uieditfield(app.Panel_4, 'text');
            app.samplingFreqEditField.ValueChangedFcn = createCallbackFcn(app, @samplingFreqEditFieldValueChanged, true);
            app.codeFreqBasisEditFieldLabel = uilabel(app.Panel_4);
            app.codeFreqBasisEditFieldLabel.Text = 'codeFreqBasis';
            app.codeFreqBasisEditField = uieditfield(app.Panel_4, 'text');
            app.codeFreqBasisEditField.ValueChangedFcn = createCallbackFcn(app, @codeFreqBasisEditFieldValueChanged, true);
            app.codeLengthEditFieldLabel = uilabel(app.Panel_4);
            app.codeLengthEditFieldLabel.Text = 'codeLength';
            app.codeLengthEditField = uieditfield(app.Panel_4, 'text');
            app.codeLengthEditField.ValueChangedFcn = createCallbackFcn(app, @codeLengthEditFieldValueChanged, true);
            app.DataFileEditFieldLabel = uilabel(app.Panel_4);
            app.DataFileEditFieldLabel.Text = 'Data File';
            app.DataFileEditField = uieditfield(app.Panel_4, 'text');
            app.DataFileEditField.Editable = 'off';
            app.SelectdatafileButton = uibutton(app.Panel_4, 'push');
            app.SelectdatafileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdatafileButtonPushed, true);
            app.SelectdatafileButton.Text = 'Select data file';
            app.Panel_5 = uipanel(app.Tab);
            app.Panel_5.Title = '控制面板';
            app.StartSimButton = uibutton(app.Panel_5, 'push');
            app.StartSimButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimButtonPushed, true);
            app.StartSimButton.Text = '开始仿真';
            app.DefaultParaButton = uibutton(app.Panel_5, 'push');
            app.DefaultParaButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultParaButtonPushed, true);
            app.DefaultParaButton.Text = '默认参数';
            app.PrintParaButton = uibutton(app.Panel_5, 'push');
            app.PrintParaButton.ButtonPushedFcn = createCallbackFcn(app, @PrintParaButtonPushed, true);
            app.PrintParaButton.Text = '打印参数';
            app.HelpParaButton = uibutton(app.Panel_5, 'push');
            app.HelpParaButton.ButtonPushedFcn = createCallbackFcn(app, @HelpParaButtonPushed, true);
            app.HelpParaButton.Text = '参数设置帮助';
            app.Panel_6 = uipanel(app.Tab);
            app.Panel_6.Title = '捕获参数设置';
            app.AcqbandkHzLabel = uilabel(app.Panel_6);
            app.AcqbandkHzLabel.Text = 'Acq. band(kHz)';
            app.AcqbandkHzEditField = uieditfield(app.Panel_6, 'text');
            app.AcqbandkHzEditField.ValueChangedFcn = createCallbackFcn(app, @AcqbandkHzEditFieldValueChanged, true);
            app.DetthresholdEditFieldLabel = uilabel(app.Panel_6);
            app.DetthresholdEditFieldLabel.Text = 'Det. threshold';
            app.DetthresholdEditField = uieditfield(app.Panel_6, 'text');
            app.DetthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @DetthresholdEditFieldValueChanged, true);
            app.Panel_7 = uipanel(app.Tab);
            app.Panel_7.Title = '其他参数';
            app.NofbytestoskipEditFieldLabel = uilabel(app.Panel_7);
            app.NofbytestoskipEditFieldLabel.Text = 'N of bytes to skip';
            app.NofbytestoskipEditField = uieditfield(app.Panel_7, 'text');
            app.NofbytestoskipEditField.ValueChangedFcn = createCallbackFcn(app, @NofbytestoskipEditFieldValueChanged, true);
            app.NofmstoprocessEditFieldLabel = uilabel(app.Panel_7);
            app.NofmstoprocessEditFieldLabel.Text = 'N of ms to process';
            app.NofmstoprocessEditField = uieditfield(app.Panel_7, 'text');
            app.NofmstoprocessEditField.ValueChangedFcn = createCallbackFcn(app, @NofmstoprocessEditFieldValueChanged, true);
            app.DatatypeEditFieldLabel = uilabel(app.Panel_7);
            app.DatatypeEditFieldLabel.Text = 'Data type';
            app.DatatypeEditField = uieditfield(app.Panel_7, 'text');
            app.DatatypeEditField.ValueChangedFcn = createCallbackFcn(app, @DatatypeEditFieldValueChanged, true);
            app.Panel_8 = uipanel(app.Tab);
            app.Panel_8.Title = '跟踪参数设置';
            app.NofchannelsEditFieldLabel = uilabel(app.Panel_8);
            app.NofchannelsEditFieldLabel.Text = 'N of channels';
            app.NofchannelsEditField = uieditfield(app.Panel_8, 'text');
            app.NofchannelsEditField.ValueChangedFcn = createCallbackFcn(app, @NofchannelsEditFieldValueChanged, true);
            app.PLLPanel = uipanel(app.Tab);
            app.PLLPanel.Title = 'PLL参数设置';
            app.BandwidthHzLabel = uilabel(app.PLLPanel);
            app.BandwidthHzLabel.Text = 'Bandwidth(Hz)';
            app.PllBandwidthEditField = uieditfield(app.PLLPanel, 'text');
            app.PllBandwidthEditField.ValueChangedFcn = createCallbackFcn(app, @PllBandwidthEditFieldValueChanged, true);
            app.DampingratioEditFieldLabel = uilabel(app.PLLPanel);
            app.DampingratioEditFieldLabel.Text = 'Damping ratio';
            app.PllDampingratioEditField = uieditfield(app.PLLPanel, 'text');
            app.PllDampingratioEditField.ValueChangedFcn = createCallbackFcn(app, @PllDampingratioEditFieldValueChanged, true);
            app.DLLPanel = uipanel(app.Tab);
            app.DLLPanel.Title = 'DLL参数设置';
            app.BandwidthHzLabel_2 = uilabel(app.DLLPanel);
            app.BandwidthHzLabel_2.Text = 'Bandwidth(Hz)';
            app.DllBandwidthEditField = uieditfield(app.DLLPanel, 'text');
            app.DllBandwidthEditField.ValueChangedFcn = createCallbackFcn(app, @DllBandwidthEditFieldValueChanged, true);
            app.DampingratioEditField_2Label = uilabel(app.DLLPanel);
            app.DampingratioEditField_2Label.Text = 'Damping ratio';
            app.DllDampingratioEditField = uieditfield(app.DLLPanel, 'text');
            app.DllDampingratioEditField.ValueChangedFcn = createCallbackFcn(app, @DllDampingratioEditFieldValueChanged, true);
            app.CorrspacingEditFieldLabel = uilabel(app.DLLPanel);
            app.CorrspacingEditFieldLabel.Text = 'Corr. spacing';
            app.CorrspacingEditField = uieditfield(app.DLLPanel, 'text');
            app.CorrspacingEditField.ValueChangedFcn = createCallbackFcn(app, @CorrspacingEditFieldValueChanged, true);
            app.Panel_9 = uipanel(app.Tab);
            app.Panel_9.Title = '定位参数设置';
            app.NavsolperiodmsEditFieldLabel = uilabel(app.Panel_9);
            app.NavsolperiodmsEditFieldLabel.Text = 'Nav. sol. period(ms)';
            app.NavsolperiodmsEditField = uieditfield(app.Panel_9, 'text');
            app.NavsolperiodmsEditField.ValueChangedFcn = createCallbackFcn(app, @NavsolperiodmsEditFieldValueChanged, true);
            app.ElevationmaskLabel = uilabel(app.Panel_9);
            app.ElevationmaskLabel.Text = 'Elevation mask(°)';
            app.ElevationmaskEditField = uieditfield(app.Panel_9, 'text');
            app.ElevationmaskEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationmaskEditFieldValueChanged, true);
            app.UTMPanel = uipanel(app.Panel_9);
            app.UTMPanel.Title = '接收机坐标（UTM）';
            app.EEditFieldLabel = uilabel(app.UTMPanel);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.UTMPanel, 'text');
            app.EEditField.ValueChangedFcn = createCallbackFcn(app, @EEditFieldValueChanged, true);
            app.NEditFieldLabel = uilabel(app.UTMPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UTMPanel, 'text');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.UEditFieldLabel = uilabel(app.UTMPanel);
            app.UEditFieldLabel.Text = 'U';
            app.UEditField = uieditfield(app.UTMPanel, 'text');
            app.UEditField.ValueChangedFcn = createCallbackFcn(app, @UEditFieldValueChanged, true);
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '北斗导航电文解析';
            app.UITable = uitable(app.Tab_4);
            app.UITable.ColumnName = {'weekNumber'; 'accuracy'; 'health'; 'T_GD'; 'IODC'; 't_oc'; 'a_f2'; 'a_f1'; 'a_f0'; 'IODE_sf2'; 'C_rs'; 'delta_n'; 'M_0'; 'C_uc'; 'e'; 'C_us'; 'sqrtA'; 't_oe'; 'C_ic'; 'omega_0'; 'C_is'; 'i_0'; 'C_rc'; 'omega'; 'omegaDot'; 'IODE_sf3'};
            app.UITable.RowName = {};
            app.NavSolveButton = uibutton(app.Tab_4, 'push');
            app.NavSolveButton.ButtonPushedFcn = createCallbackFcn(app, @NavSolveButtonPushed, true);
            app.NavSolveButton.Text = '导航电文解析';
            app.HelpNavSolveButton = uibutton(app.Tab_4, 'push');
            app.HelpNavSolveButton.ButtonPushedFcn = createCallbackFcn(app, @HelpNavSolveButtonPushed, true);
            app.HelpNavSolveButton.Text = '帮助';
            app.Label_2 = uilabel(app.Tab_4);
            app.Label_2.Text = '数据选择';
            app.SelectByDataDropDown = uidropdown(app.Tab_4);
            app.SelectByDataDropDown.Items = {'星历16参', '钟差数据', '历书数据', '电离层数据'};
            app.SelectByDataDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectByDataDropDownValueChanged, true);
            app.SelectByDataDropDown.Value = '星历16参';
            app.Label_3 = uilabel(app.Tab_4);
            app.Label_3.Text = '按帧查看';
            app.SelectBySubrameDropDown = uidropdown(app.Tab_4);
            app.SelectBySubrameDropDown.Items = {'子帧一', '子帧二', '子帧三', '子帧四'};
            app.SelectBySubrameDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectBySubrameDropDownValueChanged, true);
            app.SelectBySubrameDropDown.Value = '子帧一';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '北斗解调译码仿真';
            app.Panel_2 = uipanel(app.Tab_2);
            app.Panel_2.Title = '参数设置';
            app.DecodeScheme = uibuttongroup(app.Panel_2);
            app.DecodeScheme.SelectionChangedFcn = createCallbackFcn(app, @DecodeSchemeSelectionChanged, true);
            app.DecodeScheme.Title = '解调译码方案';
            app.ICDButton = uiradiobutton(app.DecodeScheme);
            app.ICDButton.Text = 'ICD标准译码';
            app.ICDButton.Value = true;
            app.RmFCSPAButton = uiradiobutton(app.DecodeScheme);
            app.RmFCSPAButton.Text = 'RmFC+SPA译码';
            app.Chase2Button = uiradiobutton(app.DecodeScheme);
            app.Chase2Button.Text = 'Chase2译码';
            app.StartBCHSimButton = uibutton(app.Panel_2, 'push');
            app.StartBCHSimButton.ButtonPushedFcn = createCallbackFcn(app, @StartBCHSimButtonPushed, true);
            app.StartBCHSimButton.Text = '开始仿真';
            app.SetChannel = uibuttongroup(app.Panel_2);
            app.SetChannel.Title = '信道设置';
            app.AWGNButton = uiradiobutton(app.SetChannel);
            app.AWGNButton.Text = 'AWGN信道';
            app.AWGNButton.Value = true;
            app.RayleighButton = uiradiobutton(app.SetChannel);
            app.RayleighButton.Text = 'Rayleigh信道';
            app.Panel_12 = uipanel(app.Panel_2);
            app.Panel_12.Title = '信噪比设置';
            app.Label = uilabel(app.Panel_12);
            app.Label.Text = '信噪比起始值';
            app.SNRStartEditField = uieditfield(app.Panel_12, 'text');
            app.SNRStartEditField.ValueChangedFcn = createCallbackFcn(app, @SNRStartEditFieldValueChanged, true);
            app.Label_4 = uilabel(app.Panel_12);
            app.Label_4.Text = '信噪比终止值';
            app.SNREndEditField = uieditfield(app.Panel_12, 'text');
            app.SNREndEditField.ValueChangedFcn = createCallbackFcn(app, @SNREndEditFieldValueChanged, true);
            app.Label_5 = uilabel(app.Panel_12);
            app.Label_5.Text = '仿真步长';
            app.SNRStepEditField = uieditfield(app.Panel_12, 'text');
            app.SNRStepEditField.ValueChangedFcn = createCallbackFcn(app, @SNRStepEditFieldValueChanged, true);
            app.Panel_11 = uipanel(app.Tab_2);
            app.Panel_11.Title = '仿真结果';
            app.UIAxes = uiaxes(app.Panel_11);
            title(app.UIAxes, '北斗BCH解调译码仿真结果')
            xlabel(app.UIAxes, 'SNR(dB)')
            ylabel(app.UIAxes, 'BER/PER')
            zlabel(app.UIAxes, 'Z')
            app.BCHSimResultTable = uitable(app.Panel_11);
            app.BCHSimResultTable.ColumnName = {'SNR'; 'BER'; 'PER'};
            app.BCHSimResultTable.RowName = {};
            app.Panel_3 = uipanel(app.V10UIFigure);
            app.Panel_3.Title = '系统消息';
            app.TextArea = uitextarea(app.Panel_3);
            app.TextArea.Editable = 'off';
            app.Panel_10 = uipanel(app.V10UIFigure);
            app.SaveLogButton = uibutton(app.Panel_10, 'push');
            app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLogButton.Text = 'Save Log';
            app.ClearLogButton = uibutton(app.Panel_10, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear';
            % Show the figure after all components are created
            app.V10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeidouAPP2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.V10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.V10UIFigure)
        end
    end
end